classdef GCS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MatlabGCSUIFigure            matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        FlightData                   matlab.ui.container.Tab
        UAV1Panel                    matlab.ui.container.Panel
        AngleDeg_1                   Aero.ui.control.HeadingIndicator
        degHeadingIndicatorLabel_6   matlab.ui.control.Label
        SpeedKmh_1                   Aero.ui.control.AirspeedIndicator
        kmhAirspeedIndicatorLabel_6  matlab.ui.control.Label
        Lamp_1                       matlab.ui.control.Lamp
        UAV2Panel                    matlab.ui.container.Panel
        AngleDeg_2                   Aero.ui.control.HeadingIndicator
        degHeadingIndicatorLabel_4   matlab.ui.control.Label
        SpeedKmh_2                   Aero.ui.control.AirspeedIndicator
        kmhAirspeedIndicatorLabel_4  matlab.ui.control.Label
        Lamp_2                       matlab.ui.control.Lamp
        UAV3Panel                    matlab.ui.container.Panel
        AngleDeg_3                   Aero.ui.control.HeadingIndicator
        degHeadingIndicatorLabel_5   matlab.ui.control.Label
        SpeedKmh_3                   Aero.ui.control.AirspeedIndicator
        kmhAirspeedIndicatorLabel_5  matlab.ui.control.Label
        Lamp_3                       matlab.ui.control.Lamp
        UAV4Panel                    matlab.ui.container.Panel
        AngleDeg_4                   Aero.ui.control.HeadingIndicator
        degHeadingIndicatorLabel_3   matlab.ui.control.Label
        SpeedKmh_4                   Aero.ui.control.AirspeedIndicator
        kmhAirspeedIndicatorLabel_3  matlab.ui.control.Label
        Lamp_4                       matlab.ui.control.Lamp
        UAV5Panel                    matlab.ui.container.Panel
        AngleDeg_5                   Aero.ui.control.HeadingIndicator
        degHeadingIndicatorLabel     matlab.ui.control.Label
        SpeedKmh_5                   Aero.ui.control.AirspeedIndicator
        kmhAirspeedIndicatorLabel    matlab.ui.control.Label
        Lamp_5                       matlab.ui.control.Lamp
        UserPanel                    matlab.ui.container.Panel
        AngleDeg_0                   Aero.ui.control.HeadingIndicator
        degHeadingIndicatorLabel_2   matlab.ui.control.Label
        SpeedKmh_0                   Aero.ui.control.AirspeedIndicator
        kmhAirspeedIndicatorLabel_2  matlab.ui.control.Label
        Lamp_0                       matlab.ui.control.Lamp
        DebugPanel                   matlab.ui.container.Panel
        Switch                       matlab.ui.control.ToggleSwitch
        DataTextArea                 matlab.ui.control.TextArea
        ConfigTurning                matlab.ui.container.Tab
        TrajectoryLength             matlab.ui.control.NumericEditField
        Label_5                      matlab.ui.control.Label
        FormationType                matlab.ui.control.NumericEditField
        Label_4                      matlab.ui.control.Label
        UAVColor                     matlab.ui.control.EditField
        Label_3                      matlab.ui.control.Label
        StepInterval                 matlab.ui.control.NumericEditField
        EditField_3Label             matlab.ui.control.Label
        FinalTime                    matlab.ui.control.NumericEditField
        Label_2                      matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Simulator % use which simulator
        SimuFigure % Description
        SimuAxes
    end
    
    methods (Access = private)
        
        function SetSimuFigAx(app)
            app.SimuFigure = app.MatlabGCSUIFigure;
            app.SimuAxes = app.UIAxes;
%             if isempty(app.SimuFigure)||~isgraphics(app.SimuFigure)
%                 % create fig and axis
%                 app.SimuFigure = figure;
%                 app.SimuAxes = axes;
%                 % build relationship
%                 set(app.SimuFigure,'Children',app.SimuAxes)
%                 % set position near gcs
%                 p = app.MatlabGCSUIFigure.Position;
%                 p = p + [p(3),0,max(p(3:4))-p(3),max(p(3:4))-p(4)];
%                 set(app.SimuFigure,'Position',p);
%             end
        end
        
        function setAngleSpeed(app,id)
            switch id
                case 1
                    AngleDeg = app.AngleDeg_1;
                    SpeedKmh = app.SpeedKmh_1;
                case 2
                    AngleDeg = app.AngleDeg_2;
                    SpeedKmh = app.SpeedKmh_2;
                case 3
                    AngleDeg = app.AngleDeg_3;
                    SpeedKmh = app.SpeedKmh_3;
                case 4
                    AngleDeg = app.AngleDeg_4;
                    SpeedKmh = app.SpeedKmh_4;
                case 5
                    AngleDeg = app.AngleDeg_4;
                    SpeedKmh = app.SpeedKmh_4;
                otherwise
                    AngleDeg = app.AngleDeg_0;
                    SpeedKmh = app.SpeedKmh_0;
            end
            if id==1||id==2||id==3||id==4||id==5
                AngleDeg.Value = -app.Simulator.model.model(id).q(3)/pi*180+90;
                if AngleDeg.Value>360
                    AngleDeg.Value = AngleDeg.Value-360;
                end
                if AngleDeg.Value<0
                    AngleDeg.Value = AngleDeg.Value+360;
                end
                SpeedKmh.Value = app.Simulator.model.model(id).v*60*6;
            else
                pr_dot_ave = sum(app.Simulator.model.percept.pr_dot,2)/app.Simulator.model.n;
                AngleDeg.Value = atan2(pr_dot_ave(1),pr_dot_ave(2))/pi*180;
                SpeedKmh.Value = norm(pr_dot_ave)*60*6;
            end
        end
        
        function setLampColor(app,id,cl)
            switch id
                case 1
                    Lamp = app.Lamp_1;
                case 2
                    Lamp = app.Lamp_2;
                case 3
                    Lamp = app.Lamp_3;
                case 4
                    Lamp = app.Lamp_4;
                case 5
                    Lamp = app.Lamp_5;
                otherwise
                    Lamp = app.Lamp_0;
            end
            switch cl
                case 'r'
                    LampColor = [1 0 0];
                case 'g'
                    LampColor = [0 1 0];
                case 'b'
                    LampColor = [0 0 1];
                case 'c'
                    LampColor = [0 1 1];
                case 'm'
                    LampColor = [1 0 1];
                case 'y'
                    LampColor = [1 1/2 0];
                otherwise
                    LampColor = [1 1 1]/2;
            end
            Lamp.Color = LampColor;
        end
        
        function readConfig(app)
            app.StepInterval.Value = app.Simulator.dt;
            app.FinalTime.Value = app.Simulator.T;
            app.FormationType.Value = app.Simulator.model.percept.f_curr;
            for i=1:app.Simulator.model.n
                app.UAVColor.Value(i) = app.Simulator.model.model(i).color;
            end
            app.TrajectoryLength.Value = app.Simulator.model.model(1).ntrj;
        end

        function setConfig(app)
            app.Simulator.dt = app.StepInterval.Value;
            app.Simulator.T = app.FinalTime.Value;
            app.Simulator.model.percept.f_curr = app.FormationType.Value;
            for i=1:app.Simulator.model.n
                app.Simulator.model.model(i).color = app.UAVColor.Value(i);
                app.Simulator.model.model(i).ntrj = app.TrajectoryLength.Value;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            close all
            app.Simulator = gcs_simu();
            app.readConfig();
            % set simulation
            app.SetSimuFigAx();
            for i=0:5
                app.setLampColor(i,'k');
            end
            % run simulation
            app.Simulator.run(app);
        end

        % Value changed function: Switch
        function StartDebugButtonPushed(app, event)
            if app.Switch.Value=='开'
                % set simulation
                app.SetSimuFigAx();
                app.setConfig();
                % start loop
                while app.Simulator.t0<=app.Simulator.T
                    data = app.Simulator.show(app);
                    app.DataTextArea.Value = data;
                    for i=0:app.Simulator.model.n
                        app.setAngleSpeed(i);
                        if i==0
                            app.setLampColor(i,'y');
                        else
                            app.setLampColor(i,app.Simulator.model.color(i));
                        end
                    end
                    app.Simulator.t0 = app.Simulator.t0+app.Simulator.dt;
                    if app.Simulator.model.percept.stop
                        app.Switch.Value = '关';
                        break;
                    end
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MatlabGCSUIFigure = uifigure('Visible', 'off');
            app.MatlabGCSUIFigure.Name = 'Matlab GCS';
            app.UIAxes = uiaxes(app.MatlabGCSUIFigure);
            title(app.UIAxes, '地图')
            xlabel(app.UIAxes, 'X（km）')
            ylabel(app.UIAxes, 'Y（km）')
            zlabel(app.UIAxes, 'Z')
            app.TabGroup = uitabgroup(app.MatlabGCSUIFigure);
            app.FlightData = uitab(app.TabGroup);
            app.FlightData.Title = '飞行数据';
            app.DebugPanel = uipanel(app.FlightData);
            app.DebugPanel.Title = '信息中心';
            app.DataTextArea = uitextarea(app.DebugPanel);
            app.Switch = uiswitch(app.DebugPanel, 'toggle');
            app.Switch.Items = {'关', '开'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @StartDebugButtonPushed, true);
            app.Switch.Value = '关';
            app.UserPanel = uipanel(app.FlightData);
            app.UserPanel.Title = '遥控';
            app.Lamp_0 = uilamp(app.UserPanel);
            app.kmhAirspeedIndicatorLabel_2 = uilabel(app.UserPanel);
            app.kmhAirspeedIndicatorLabel_2.Text = '速度（km/h）';
            app.SpeedKmh_0 = uiaeroairspeed(app.UserPanel);
            app.degHeadingIndicatorLabel_2 = uilabel(app.UserPanel);
            app.degHeadingIndicatorLabel_2.Text = '航向角（deg）';
            app.AngleDeg_0 = uiaeroheading(app.UserPanel);
            app.UAV5Panel = uipanel(app.FlightData);
            app.UAV5Panel.Title = 'UAV5';
            app.Lamp_5 = uilamp(app.UAV5Panel);
            app.kmhAirspeedIndicatorLabel = uilabel(app.UAV5Panel);
            app.kmhAirspeedIndicatorLabel.Text = '速度（km/h）';
            app.SpeedKmh_5 = uiaeroairspeed(app.UAV5Panel);
            app.degHeadingIndicatorLabel = uilabel(app.UAV5Panel);
            app.degHeadingIndicatorLabel.Text = '航向角（deg）';
            app.AngleDeg_5 = uiaeroheading(app.UAV5Panel);
            app.UAV4Panel = uipanel(app.FlightData);
            app.UAV4Panel.Title = 'UAV4';
            app.Lamp_4 = uilamp(app.UAV4Panel);
            app.kmhAirspeedIndicatorLabel_3 = uilabel(app.UAV4Panel);
            app.kmhAirspeedIndicatorLabel_3.Text = '速度（km/h）';
            app.SpeedKmh_4 = uiaeroairspeed(app.UAV4Panel);
            app.degHeadingIndicatorLabel_3 = uilabel(app.UAV4Panel);
            app.degHeadingIndicatorLabel_3.Text = '航向角（deg）';
            app.AngleDeg_4 = uiaeroheading(app.UAV4Panel);
            app.UAV3Panel = uipanel(app.FlightData);
            app.UAV3Panel.Title = 'UAV3';
            app.Lamp_3 = uilamp(app.UAV3Panel);
            app.kmhAirspeedIndicatorLabel_5 = uilabel(app.UAV3Panel);
            app.kmhAirspeedIndicatorLabel_5.Text = '速度（km/h）';
            app.SpeedKmh_3 = uiaeroairspeed(app.UAV3Panel);
            app.degHeadingIndicatorLabel_5 = uilabel(app.UAV3Panel);
            app.degHeadingIndicatorLabel_5.Text = '航向角（deg）';
            app.AngleDeg_3 = uiaeroheading(app.UAV3Panel);
            app.UAV2Panel = uipanel(app.FlightData);
            app.UAV2Panel.Title = 'UAV2';
            app.Lamp_2 = uilamp(app.UAV2Panel);
            app.kmhAirspeedIndicatorLabel_4 = uilabel(app.UAV2Panel);
            app.kmhAirspeedIndicatorLabel_4.Text = '速度（km/h）';
            app.SpeedKmh_2 = uiaeroairspeed(app.UAV2Panel);
            app.degHeadingIndicatorLabel_4 = uilabel(app.UAV2Panel);
            app.degHeadingIndicatorLabel_4.Text = '航向角（deg）';
            app.AngleDeg_2 = uiaeroheading(app.UAV2Panel);
            app.UAV1Panel = uipanel(app.FlightData);
            app.UAV1Panel.Title = 'UAV1';
            app.Lamp_1 = uilamp(app.UAV1Panel);
            app.kmhAirspeedIndicatorLabel_6 = uilabel(app.UAV1Panel);
            app.kmhAirspeedIndicatorLabel_6.Text = '速度（km/h）';
            app.SpeedKmh_1 = uiaeroairspeed(app.UAV1Panel);
            app.degHeadingIndicatorLabel_6 = uilabel(app.UAV1Panel);
            app.degHeadingIndicatorLabel_6.Text = '航向角（deg）';
            app.AngleDeg_1 = uiaeroheading(app.UAV1Panel);
            app.ConfigTurning = uitab(app.TabGroup);
            app.ConfigTurning.Title = '参数设置';
            app.Label_2 = uilabel(app.ConfigTurning);
            app.Label_2.Text = '总时';
            app.FinalTime = uieditfield(app.ConfigTurning, 'numeric');
            app.FinalTime.Value = 2;
            app.EditField_3Label = uilabel(app.ConfigTurning);
            app.EditField_3Label.Text = '步长';
            app.StepInterval = uieditfield(app.ConfigTurning, 'numeric');
            app.StepInterval.Value = 0.02;
            app.Label_3 = uilabel(app.ConfigTurning);
            app.Label_3.Text = '颜色';
            app.UAVColor = uieditfield(app.ConfigTurning, 'text');
            app.UAVColor.Value = 'rgbcm';
            app.Label_4 = uilabel(app.ConfigTurning);
            app.Label_4.Text = '队形';
            app.FormationType = uieditfield(app.ConfigTurning, 'numeric');
            app.FormationType.Value = 1;
            app.Label_5 = uilabel(app.ConfigTurning);
            app.Label_5.Text = '轨迹';
            app.TrajectoryLength = uieditfield(app.ConfigTurning, 'numeric');
            app.TrajectoryLength.Value = 300;
            % Show the figure after all components are created
            app.MatlabGCSUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GCS

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MatlabGCSUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MatlabGCSUIFigure)
        end
    end
end